classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        EditField   matlab.ui.control.EditField
        equal       matlab.ui.control.Button
        point       matlab.ui.control.Button
        zero        matlab.ui.control.Button
        power       matlab.ui.control.Button
        plus        matlab.ui.control.Button
        three       matlab.ui.control.Button
        two         matlab.ui.control.Button
        one         matlab.ui.control.Button
        minus       matlab.ui.control.Button
        six         matlab.ui.control.Button
        five        matlab.ui.control.Button
        four        matlab.ui.control.Button
        mult        matlab.ui.control.Button
        nine        matlab.ui.control.Button
        eight       matlab.ui.control.Button
        seven       matlab.ui.control.Button
        division    matlab.ui.control.Button
        percentage  matlab.ui.control.Button
        del         matlab.ui.control.Button
        clear       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: zero
        function zeroButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "0";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: one
        function oneButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "1";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: two
        function twoButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "2";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: three
        function threeButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "3";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: four
        function fourButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "4";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: five
        function fiveButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "5";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: six
        function sixButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "6";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: seven
        function sevenButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "7";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: eight
        function eightButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "8";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: nine
        function nineButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "9";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: point
        function pointButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = ".";
            if ismember('.', current_value)
                app.EditField.Value = current_value;
            else
                app.EditField.Value = strcat(current_value, new_value);
            end
        end

        % Button pushed function: power
        function powerButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "^";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: plus
        function plusButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "+";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: minus
        function minusButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "-";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: mult
        function multButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "x";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: division
        function divisionButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = "/";
            app.EditField.Value = strcat(current_value, new_value);
        end

        % Button pushed function: del
        function delButtonPushed(app, event)
            current_value = app.EditField.Value;
            app.EditField.Value = current_value(1: end-1);
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            new_value = "";
            app.EditField.Value = new_value;
        end

        % Button pushed function: percentage
        function percentageButtonPushed(app, event)
            current_value = app.EditField.Value;
            current_value = str2double(current_value);
            current_value = current_value/100;
            app.EditField.Value = string(current_value);
        end

        % Button pushed function: equal
        function equalButtonPushed(app, event)
            current_value = app.EditField.Value;
            new_value = string(current_value);
            new_value = strsplit(new_value, ["+", "-", "/", "x", "^"]);
            if length(new_value) == 2
                if ismember('+', current_value)
                    num1 = str2double(new_value(1));
                    num2 = str2double(new_value(2));
                    app.EditField.Value = string(num1 + num2);
                elseif ismember('-', current_value)
                    num1 = str2double(new_value(1));
                    num2 = str2double(new_value(2));
                    app.EditField.Value = string(num1 - num2);
                elseif ismember('/', current_value)
                    num1 = str2double(new_value(1));
                    num2 = str2double(new_value(2));
                    app.EditField.Value = string(num1 / num2);
                elseif ismember('x', current_value)
                    num1 = str2double(new_value(1));
                    num2 = str2double(new_value(2));
                    app.EditField.Value = string(num1*num2);
                else
                    num1 = str2double(new_value(1));
                    num2 = str2double(new_value(2));
                    app.EditField.Value = string(num1^num2);
                end
            elseif length(new_value) == 1
                app.EditField.Value = new_value;
            else
                app.EditField.Value = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.clear = uibutton(app.UIFigure, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = 'AC';
            app.del = uibutton(app.UIFigure, 'push');
            app.del.ButtonPushedFcn = createCallbackFcn(app, @delButtonPushed, true);
            app.del.Text = 'del';
            app.percentage = uibutton(app.UIFigure, 'push');
            app.percentage.ButtonPushedFcn = createCallbackFcn(app, @percentageButtonPushed, true);
            app.percentage.Text = '%';
            app.division = uibutton(app.UIFigure, 'push');
            app.division.ButtonPushedFcn = createCallbackFcn(app, @divisionButtonPushed, true);
            app.division.Text = '/';
            app.seven = uibutton(app.UIFigure, 'push');
            app.seven.ButtonPushedFcn = createCallbackFcn(app, @sevenButtonPushed, true);
            app.seven.Text = '7';
            app.eight = uibutton(app.UIFigure, 'push');
            app.eight.ButtonPushedFcn = createCallbackFcn(app, @eightButtonPushed, true);
            app.eight.Text = '8';
            app.nine = uibutton(app.UIFigure, 'push');
            app.nine.ButtonPushedFcn = createCallbackFcn(app, @nineButtonPushed, true);
            app.nine.Text = '9';
            app.mult = uibutton(app.UIFigure, 'push');
            app.mult.ButtonPushedFcn = createCallbackFcn(app, @multButtonPushed, true);
            app.mult.Text = 'x';
            app.four = uibutton(app.UIFigure, 'push');
            app.four.ButtonPushedFcn = createCallbackFcn(app, @fourButtonPushed, true);
            app.four.Text = '4';
            app.five = uibutton(app.UIFigure, 'push');
            app.five.ButtonPushedFcn = createCallbackFcn(app, @fiveButtonPushed, true);
            app.five.Text = '5';
            app.six = uibutton(app.UIFigure, 'push');
            app.six.ButtonPushedFcn = createCallbackFcn(app, @sixButtonPushed, true);
            app.six.Text = '6';
            app.minus = uibutton(app.UIFigure, 'push');
            app.minus.ButtonPushedFcn = createCallbackFcn(app, @minusButtonPushed, true);
            app.minus.Text = '-';
            app.one = uibutton(app.UIFigure, 'push');
            app.one.ButtonPushedFcn = createCallbackFcn(app, @oneButtonPushed, true);
            app.one.Text = '1';
            app.two = uibutton(app.UIFigure, 'push');
            app.two.ButtonPushedFcn = createCallbackFcn(app, @twoButtonPushed, true);
            app.two.Text = '2';
            app.three = uibutton(app.UIFigure, 'push');
            app.three.ButtonPushedFcn = createCallbackFcn(app, @threeButtonPushed, true);
            app.three.Text = '3';
            app.plus = uibutton(app.UIFigure, 'push');
            app.plus.ButtonPushedFcn = createCallbackFcn(app, @plusButtonPushed, true);
            app.plus.Text = '+';
            app.power = uibutton(app.UIFigure, 'push');
            app.power.ButtonPushedFcn = createCallbackFcn(app, @powerButtonPushed, true);
            app.power.Text = '^';
            app.zero = uibutton(app.UIFigure, 'push');
            app.zero.ButtonPushedFcn = createCallbackFcn(app, @zeroButtonPushed, true);
            app.zero.Text = '0';
            app.point = uibutton(app.UIFigure, 'push');
            app.point.ButtonPushedFcn = createCallbackFcn(app, @pointButtonPushed, true);
            app.point.Text = '.';
            app.equal = uibutton(app.UIFigure, 'push');
            app.equal.ButtonPushedFcn = createCallbackFcn(app, @equalButtonPushed, true);
            app.equal.Text = '=';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end