classdef Image_processing_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        NegativeButton   matlab.ui.control.Button
        GrayScaleButton  matlab.ui.control.Button
        LoadImageButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image = zeros(2^8, 3); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [imagename, imagepath]  = uigetfile({'*.jpg'}, 'File Selector');
            imagepath = strcat(imagepath, imagename);
            app.image = imread(imagepath);
            imshow(app.image, 'Parent', app.UIAxes);
        end

        % Button pushed function: GrayScaleButton
        function GrayScaleButtonPushed(app, event)
            app.image = rgb2gray(app.image);
            imshow(app.image, 'Parent', app.UIAxes);
        end

        % Button pushed function: NegativeButton
        function NegativeButtonPushed(app, event)
            scales = 2^8;
            app.image = (scales-1) - app.image;
            imshow(app.image, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.GrayScaleButton = uibutton(app.UIFigure, 'push');
            app.GrayScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayScaleButtonPushed, true);
            app.GrayScaleButton.Text = 'Gray Scale';
            app.NegativeButton = uibutton(app.UIFigure, 'push');
            app.NegativeButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeButtonPushed, true);
            app.NegativeButton.Text = 'Negative';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_processing_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end