classdef Main_function < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        PlotButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        waveform; % Description
        wavelength; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x = 0: 0.05: 30;
            if app.waveform == "Sine Wave"
                plot(app.UIAxes, x, sin(app.wavelength*pi*x));
            elseif app.waveform == "Cosine Wave"
                plot(app.UIAxes, x, cos(app.wavelength*pi*x));
            else
                plot(app.UIAxes, x, tan(app.wavelength*pi*x));
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GRAPH')
            xlabel(app.UIAxes, 'WAVELENGTH')
            ylabel(app.UIAxes, 'AMPLITUDE')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 30];
            app.UIAxes.YLim = [-1 1];
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_function

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end