classdef Simple_wave_function < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        PLOTButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        type; % Description
    end
    
    methods (Access = private)
        
        function results = pri_wave(app, wave_model)
            app.type = wave_model;
            x = 0: 0.005: 50;
            if app.type == 0
                y = sin(x);
            elseif app.type == 1
                y = cos(x);
            else
                y = tan(x);
            end
            plot(app.UIAxes, x, y);
        end
    end
    
    methods (Access = public)
        
        function results = pub_wave(app, wave_model)
            app.type = wave_model;
            PLOTButtonPushed(app, event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            pri_wave(app, app.type);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave Graph')
            xlabel(app.UIAxes, 'Wavelength')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 50];
            app.UIAxes.YLim = [-1 1];
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simple_wave_function

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end