classdef Sine_Wave_Modification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Slider2      matlab.ui.control.Slider
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slider1value = 10; % Description
        x = 0: 0.005: 50;  % Description
        slider2value = 0.1; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.slider1value = value;
            plot(app.UIAxes, app.x, app.slider2value*sin(value*app.x))
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            SliderValueChanged(app, event);
            app.slider2value = value;
            plot(app.UIAxes, app.x, value*sin(app.slider1value*app.x));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sine  wave')
            xlabel(app.UIAxes, 'Angle')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XTick = [0 1 2 3 4 5];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.UIAxes.YTick = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.75'; '-0.5'; '-0.25'; '0'; '0.25'; '0.5'; '0.75'; '1'};
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 50];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10;
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [0 1];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sine_Wave_Modification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end